classdef Geodash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TabGroup     matlab.ui.container.TabGroup
        MainMenuTab  matlab.ui.container.Tab
        StartButton  matlab.ui.control.Button
        ENGRDashTab  matlab.ui.container.Tab
        JumpButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ENGRDashTab; % When clickling the start button it switches to the game tab

            %Starts music
            music = true; % Sets intial state of music till the game ends then it turns off music by returning false(still needs to be made)
            [y,Fs] = audioread('Short.mp3'); % Gets sample rate and sample frequency from audio file
            sound(y,Fs) % Plays sound using the sample rate and frequency
            
            % Initializes plot area
            hold(app.UIAxes, 'on'); % Allows you to draw multiple shapes without deleting previous ones
            app.UIAxes.XColor = 'none'; % Sets x-axis color
            app.UIAxes.YColor = 'none'; % Sets y-axis color
            app.UIAxes.Color = 'none'; % Sets figure color(background color)
            app.UIAxes.XLabel.String = ''; % Hide x-axis label
            app.UIAxes.YLabel.String = ''; % Hides y-axis label
            x_values = (0 : 1 : 100); % Sets x-value range of plot
            y_values = (0 : 1 : 100); % Sets y-value range of plot
            plot(app.UIAxes , x_values , y_values , 'Color', 'none') % Plots a white line to set the x and y ranges of the plot
            hold(app.UIAxes)
            plot(app.UIAxes , x_values , linspace(0 , 0 , 101) , 'Color', 'k')
            xlim(app.UIAxes , [0 100]); % Sustains x-value range
            ylim(app.UIAxes, [0 100]); % Sustains y-value range

            %Sets Player properties
            player.length = 5; % m
            player.width = 5; % m
            player.Xposition = 10; % m
            player.Yposition = 0; % m
            player.Xvelocity = 0; % m/s
            player.Yvelocity = 0; % m/s

            % Draws player on plot
            Player_orientation = rectangle(app.UIAxes, 'Position', [player.Xposition player.Yposition player.length player.width], 'FaceColor', 'r');
        end

        % Button pushed function: JumpButton
        function JumpButtonPushed(app, event)

            % Set player properties again and environment properties
            player.length = 5; % m
            player.width = 5; % m
            player.Yposition = 0; % m
            jump_Yvelocity = 12; % m/s
            jump_Xvelocity = 0; % m/s
            gravity = -3; % m/s^2


            for t = linspace(0 , 8 , 10)

            y_jump = (jump_Yvelocity .* t) + (1/2 .* (gravity) .* t.^2);
            x_jump =  ((jump_Xvelocity .* t) + 10);
            
            pause(0.01)
            Player_orientation = rectangle(app.UIAxes, 'Position', [x_jump y_jump player.length player.width], 'FaceColor', 'red' , 'EdgeColor', 'k');
            pause(0.00000000001)
            delete(Player_orientation)
            
            end

            % problems: Need box to stop when its bottom edge hits the
            % x-axis. Get rid of previous boxes as it jumps which inlcude
            % the intial one.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainMenuTab = uitab(app.TabGroup);
            app.MainMenuTab.Title = 'Main Menu';
            app.StartButton = uibutton(app.MainMenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ENGRDashTab = uitab(app.TabGroup);
            app.ENGRDashTab.Title = 'ENGR Dash';
            app.UIAxes = uiaxes(app.ENGRDashTab);
            xlabel(app.UIAxes, 'x-axis(m)')
            ylabel(app.UIAxes, 'y-axis(m)')
            app.JumpButton = uibutton(app.ENGRDashTab, 'push');
            app.JumpButton.ButtonPushedFcn = createCallbackFcn(app, @JumpButtonPushed, true);
            app.JumpButton.Text = 'Jump';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Geodash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end