classdef Geodash < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HowtoPlayTab                   matlab.ui.container.Tab
        Label_9                        matlab.ui.control.Label
        ObjectiveLabel                 matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        GettingStartedLabel_2          matlab.ui.control.Label
        Image3                         matlab.ui.control.Image
        MainMenuTab                    matlab.ui.container.Tab
        DevelopersErnestoTellezPerezShivPatelChatalsohelpedabittooLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        CharcterColorColorPicker       matlab.ui.control.ColorPicker
        CharcterColorColorPickerLabel  matlab.ui.control.Label
        CharacterEdgeColorColorPicker  matlab.ui.control.ColorPicker
        CharacterEdgeColorColorPickerLabel  matlab.ui.control.Label
        TitleImage                     matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        ENGRDashTab                    matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        PlayAgainButton                matlab.ui.control.Button
        Image4                         matlab.ui.control.Image
        scoreField                     matlab.ui.control.NumericEditField
        SCOREEditFieldLabel            matlab.ui.control.Label
        PauseButton                    matlab.ui.control.Button
        JumpButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CommonIssuesTab                matlab.ui.container.Tab
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        CommonIssuesPossibleSolutionsLabel_2  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Universal variables that can be used across function and callbacks

            % Used for music
            music
            music_end
            music_coin
            y
            y_end
            Fs
            Fs_end
            musicLoopTimer
            musicDisabled logical = false;  % prevents music from auto-restarting
            y_coin
            Fs_coin

            
            % Player and Enviornment properties
            player_length = 5;
            player_width = 5;
            player_Xposition = 30;
            player_Yposition = 0;
            player_Xvelocity = 0;
            player_Yvelocity = 0;
            Player_orientation
            jump_Yvelocity = 17.5;
            jump_Xvelocity = 0;
            gravity = -3
            x_jump
            y_jump

            % Inductor properties
            inductor1_img_path
            %inductor1_img
            inductor2_img_path
            %inductor2_img
            disp_inductor1
            disp_inductor2

            % Capcitor properties
            capacitor1_img_path
            capacitor2_img_path
            %capacitor1_img
            %capacitor2_img
            disp_capacitor1
            disp_capacitor2

            % Resistor properties
            resistor1_img_path
            resistor2_img_path
            %resistor1_img
            %resistor2_img
            disp_resistor1
            disp_resistor2

            % Truss properties
            truss1_img_path
            %truss1_img
            disp_truss1
            
            % Hole properties
            hole1_img
            hole1_img_path
            disp_hole1

            % Obstacle Starting Positions and Choosing
            obstacle_xi1 = 115;
            obstacle_xi2 = 125;
            obstacle_xi3 = 152.5;
            obstacle_xi4 = 160;
            obstacle_xi5 = 130;
            obs_choose
            obstacle_yi1 = 0.58;
            obstacle_yi2 = 40;
            obstacle_yi3 = -5.5;
            obstacle_yi4 = 5.5;
            obstacle_yi5 = -15;
            obstacle_yi6 = 15;
            obstacle_yi7 = 0;
            obstacle_yi8 = 25;
            obstacle_yi9 = -0.6;
            obstacle_yi10 = 0.6;

            
            % Image properties for Obstacles
            img1
            alpha1
            img2
            alpha2
            img3
            alpha3
            img4
            alpha4
            img5
            alpha5
            img6
            alpha6
            img7
            alpha7


    playerBoxHandle   % Stores the player bounding box rectangle object
    obsBoxHandle1     % Stores the obstacle bounding box rectangle object
    obsBoxHandle2       % Handle for secondary obstacle hitbox (custom size/position)


            isPaused logical = false;
    end
    
    % ----------- where the timers go -------------
    properties (Access = private)
           titleTimer         
           titleImageBaseY = 277;

           jumpTimer
           jumpTime = 0

           obstacleSpawnTimer

           % array holding active timers to keep track of them 
           activeObstacleTimers = {}
    end
    
    methods (Access = private)
        
        % stops the timer and deletes it but first checks if it is not
        % already empty and exists
        function stopAppTimers(app)
            if ~isempty(app.titleTimer) && isvalid(app.titleTimer)
                stop(app.titleTimer);
                delete(app.titleTimer);
            end
        end

        % function called when closing game
        function onClose(app, ~)

            stopAppTimers(app);  % Call the cleanup funciton

            % Stop and delete music loop timer
            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                stop(app.musicLoopTimer);
                delete(app.musicLoopTimer);
            end
            delete(app);         % Close the app
        end
        
        % function that animates the title screen
        function animateTitleImage(app)
          
          % this saves the value of t between funciton calls (kinda acts
          % like a public variable but only for this funciton)
          persistent t

          % initiallizes t if it is empty
          if isempty(t)
             t = 0;
          end
          
          % the increment everytime this function is called by the timer
          t = t + 0.032;

         % Bouncing motion 10 pixels t per frame 
          bounce = 10 * sin(t); 

          % current position
          oldPos = app.TitleImage.Position;

          % new y position
          newY = app.titleImageBaseY + bounce;
        
        % Apply new position to title image
          app.TitleImage.Position = [oldPos(1), newY, oldPos(3), oldPos(4)];
          end
        
        function updateJumpFrame(app)
            app.jumpTime = app.jumpTime + 0.1;
            t = app.jumpTime;

            app.y_jump = (app.jump_Yvelocity * t) + (1/2 .* (app.gravity) * t^2); % y-motion equation
            app.x_jump =  ((app.jump_Xvelocity * t) + app.player_Xposition); % x-motion equation

            app.Player_orientation.Position = [app.x_jump, app.y_jump, app.player_length, app.player_width];
            
            % check pause functionality
            while app.isPaused
                
                pause(0.1);
            
            end

            if app.y_jump <= 0
                stop(app.jumpTimer);
                delete(app.jumpTimer);
            
                app.player_Yposition = 0;  % Reset internal Y
                
                app.JumpButton.Enable = 'on';
                focus(app.UIFigure);
                return;
            end
        end

    function isColliding = checkCollision(~, playerPos, obsPos)
    % playerPos and obsPos are of the form [x, y, width, height]
    % Index's the edge positions
    px = playerPos(1); py = playerPos(2);
    pw = playerPos(3); ph = playerPos(4);

    ox = obsPos(1); oy = obsPos(2);
    ow = obsPos(3); oh = obsPos(4);
    
    % Checks if there is a collisons by check the edges relative to the
    % obstacle
    isColliding = ~(px + pw < ox || px > ox + ow || ...
                    py + ph < oy || py > oy + oh);
    end

function checkAndLoopMusic(app)
    % Only check if music is enabled and not paused
    if ~app.musicDisabled && ~app.isPaused
        if isempty(app.music) || ~isplaying(app.music)
            % Restart the music
            try
                app.music = audioplayer(app.y, app.Fs);
                play(app.music);
            catch ME
                disp('Error restarting music:');
                disp(ME.message);
            end
        end
    end
end


function restartMusicLoop(app)
    % Clean up existing resources
    if ~isempty(app.music) && isvalid(app.music)
        stop(app.music);
    end
    if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
        stop(app.musicLoopTimer);
        delete(app.musicLoopTimer);
    end
    
    % Create fresh instances
    app.music = audioplayer(app.y, app.Fs);
    play(app.music);
    
    app.musicLoopTimer = timer(...
        'ExecutionMode', 'fixedSpacing', ...
        'Period', 0.1, ...
        'TimerFcn', @(~,~) checkAndLoopMusic(app));
    start(app.musicLoopTimer);
end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %store original y position of title image
            app.titleImageBaseY = app.TitleImage.Position(2);
            
            % continuously calls the animateTitleImage every 0.016s (60fps) 
            app.titleTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.016, ...
                'TimerFcn', @(~,~) animateTitleImage(app));
            start(app.titleTimer);
            
            % stops when closing game, 
            app.UIFigure.CloseRequestFcn = @(src, event) onClose(app);
            
            % Music reads
            [app.y , app.Fs] = audioread('mainTheme.mp3'); % Gets sample rate and sample frequency from audio file
            [app.y_end , app.Fs_end] = audioread('death sound.mp3'); % Gets sample rate and sample frequency from audio file
            [app.y_coin, app.Fs_coin] = audioread('scoreUp.mp3'); % Gets sample rate and sample frequency from audio file

            % When key is pressed it calls the UIFigureKeyPress
            % function that will be used to allow space bar as a jump
            app.UIFigure.KeyPressFcn = @(src, event) UIFigureKeyPress(app, event);

            % Obstacles
            %Inductors
            app.inductor1_img_path = fullfile(pwd,'Inductorvert.png'); % Vertical Inductor image
            app.inductor2_img_path = fullfile(pwd,'Inductorhorz.png'); % Horizontal Inductor image
            %Capacitors
            app.capacitor1_img_path = fullfile(pwd,'Capacitorvert.png'); % Vertical Capacitor image
            app.capacitor2_img_path = fullfile(pwd,'Capacitorhorz.png'); % Horizontal Capacitor image
            %Resistors
            app.resistor1_img_path = fullfile(pwd,'Resistorvert.png'); % Vertical Resistor image
            app.resistor2_img_path = fullfile(pwd,'Resistorhorz.png'); % Horizontal Resistor image
            %Truss's
            app.truss1_img_path = fullfile(pwd,'Truss1.png'); % Simple truss
            %Rocket's
            app.hole1_img_path = fullfile(pwd,'Hole1.png');

            % Obstacle reads
            %Resistor reads
            %app.resistor1_img = imread(app.resistor1_img_path);
            %app.resistor2_img = imread(app.resistor2_img_path);
            [app.img1, ~, app.alpha1] = imread(app.resistor1_img_path);
            [app.img2, ~, app.alpha2] = imread(app.resistor2_img_path);

            % Inductor reads
            %app.inductor1_img = imread(app.inductor1_img_path);
            %app.inductor2_img = imread(app.inductor2_img_path);
            [app.img3, ~, app.alpha3] = imread(app.inductor1_img_path);
            [app.img4, ~, app.alpha4] = imread(app.inductor2_img_path);

            %Capacitor reads
            %app.capacitor1_img = imread(app.capacitor1_img_path);
            %app.capacitor2_img = imread(app.capacitor2_img_path);
            [app.img5, ~, app.alpha5] = imread(app.capacitor1_img_path);
            [app.img6, ~, app.alpha6] = imread(app.capacitor2_img_path);

            % Truss reads
            %app.truss1_img = imread(app.truss1_img_path);
            [app.img7, ~, app.alpha7] = imread(app.truss1_img_path);

            % Hole reads
            app.hole1_img = imread(app.hole1_img_path);

            app.playerBoxHandle = gobjects(0);  % Initialize as empty graphics object
            app.obsBoxHandle1 = gobjects(0);
            app.obsBoxHandle2 = gobjects(0);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            app.TabGroup.SelectedTab = app.ENGRDashTab; % When clickling the start button it switches to the game tab

        % Stores and plays background music
        app.music = audioplayer(app.y, app.Fs);
        play(app.music);

        % Stores game over music
        app.music_end = audioplayer(app.y_end, app.Fs_end);

        % Create a timer to monitor and loop the music
        app.musicLoopTimer = timer( ...
        'ExecutionMode', 'fixedSpacing', ...
        'Period', 1.0, ...
        'TimerFcn', @(~,~) checkAndLoopMusic(app));

        start(app.musicLoopTimer);


            % Initializes plot area
            hold(app.UIAxes, 'on'); % Allows you to draw multiple shapes without deleting previous ones
            app.UIAxes.XColor = 'none'; % Sets x-axis color
            app.UIAxes.YColor = 'none'; % Sets y-axis color
            app.UIAxes.Color = 'none'; % Sets figure color(background color)
            app.UIAxes.XLabel.String = ''; % Hide x-axis label
            app.UIAxes.YLabel.String = ''; % Hides y-axis label
            x_values = linspace(0, 115, 115); % Sets x-value range of plot
            y_values = linspace(-15, 100, 115); % Sets y-value range of plot
            plot(app.UIAxes , x_values , y_values , 'Color', 'none') % Plots a invisible line to set the x and y ranges of the plot
            hold(app.UIAxes)
            plot(app.UIAxes , x_values , linspace(0 , 0 , 115) , 'Color', 'k', 'LineWidth', 5)
            axis(app.UIAxes , 'equal') % Helps to keep shapes accurate to there dimensions given(mostly for player)
            xlim(app.UIAxes , [0 115]); % Sustains x-value range
            ylim(app.UIAxes, [-15 100]); % Sustains y-value range

            % Draws player on plot
            app.Player_orientation = rectangle(app.UIAxes, ...
                'Position', [app.player_Xposition app.player_Yposition app.player_length app.player_width],...
                'FaceColor', app.CharcterColorColorPicker.Value ,...
                'EdgeColor', app.CharacterEdgeColorColorPicker.Value ,...
                'LineWidth', 2);

            % bring focus to game so keyboard input works
            focus(app.UIFigure);

            % Score set to zero at start
            app.scoreField.Value = 0;

            while true
            
                % Randomly choose obstacle type
                app.obs_choose = randi(8);
            
                if app.obs_choose == 1
                    % Resistor 1
                    app.disp_resistor1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi2], ...
                        'YData', [app.obstacle_yi1 , app.obstacle_yi2], ...
                        'CData' , app.img1 , 'AlphaData', app.alpha1);
            
                    for dX = 0 : 1 : 125
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_resistor1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];
                        
                      
                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX + 4.5;
                        obsY = app.obstacle_yi1;
                        obsW = app.obstacle_xi2 - app.obstacle_xi1 - 9;
                        obsH = app.obstacle_yi2 - app.obstacle_yi1;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Secondary hitbox
                        obsRect2 = [obsX - 4.5 , ...
                        obsY + 10 , ...
                        obsW + 9 , ...
                        obsH - 20 ];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1) || app.checkCollision(playerRect, obsRect2)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return; % Exits loop if there is a collisons
                        end              
 
                        pause(0.035)
                    end
            
                    delete(app.disp_resistor1);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
                    
       
                elseif app.obs_choose == 2
                    % Resistor 2
                    app.disp_resistor2 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi3], ...
                        'YData', [app.obstacle_yi3 , app.obstacle_yi4], ...
                        'CData' , app.img2 , 'AlphaData', app.alpha2);
            
                    for dX = 0 : 1 : 153
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_resistor2.XData = [app.obstacle_xi1 - dX, app.obstacle_xi3 - dX];


                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX;
                        obsY = app.obstacle_yi3 + 5.5;
                        obsW = app.obstacle_xi3 - app.obstacle_xi1;
                        obsH = app.obstacle_yi4 - app.obstacle_yi3 - 10.9;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return;  % Exit the loop to stop further movement
                        end  

                        pause(0.035)
                    end
            
                    delete(app.disp_resistor2);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
        
 
                elseif app.obs_choose == 3
                    % Inductor 1
                    app.disp_inductor1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi2], ...
                        'YData', [app.obstacle_yi1 , app.obstacle_yi2], ...
                        'CData' , app.img3 , 'AlphaData', app.alpha3);
            
                    for dX = 0 : 1 : 125
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_inductor1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];

                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX + 4;
                        obsY = app.obstacle_yi1;
                        obsW = app.obstacle_xi2 - app.obstacle_xi1 - 9;
                        obsH = app.obstacle_yi2 - app.obstacle_yi1;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Secondary hitbox
                        obsRect2 = [obsX - 2.5 , ...
                        obsY + 4, ...
                        obsW + 5, ...
                        obsH - 8];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;


                        % Check collision
                        if app.checkCollision(playerRect, obsRect1) || app.checkCollision(playerRect, obsRect2)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                            play(app.music_end) % Plays gamer over sound

                        return; % Exits loop if there is a collisons
                        end

                        pause(0.035)
                    end
            
                    delete(app.disp_inductor1);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);

                elseif app.obs_choose == 4
                    % Inductor 2
                    app.disp_inductor2 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi3], ...
                        'YData', [app.obstacle_yi3 , app.obstacle_yi4], ...
                        'CData' , app.img4 , 'AlphaData', app.alpha4);
            
                    for dX = 0 : 1 : 153
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_inductor2.XData = [app.obstacle_xi1 - dX, app.obstacle_xi3 - dX];

                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX;
                        obsY = app.obstacle_yi3 + 5.5;
                        obsW = app.obstacle_xi3 - app.obstacle_xi1;
                        obsH = app.obstacle_yi4 - app.obstacle_yi3 - 10.9;
                        obsRect1 = [obsX, obsY, obsW, obsH];


                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return;  % Exit the loop to stop further movement
                        end     

                        pause(0.035)
                    end
            
                    delete(app.disp_inductor2);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);

                elseif app.obs_choose == 5
                    % Capacitor 1
                    app.disp_capacitor1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi2], ...
                        'YData', [app.obstacle_yi5 , app.obstacle_yi6], ...
                        'CData' , app.img5 , 'AlphaData', app.alpha5);
            
                    for dX = 0 : 1 : 125
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_capacitor1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi2 - dX];


                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX - 0.0625;
                        obsY = app.obstacle_yi5;
                        obsW = app.obstacle_xi2 - app.obstacle_xi1 + 0.125;
                        obsH = app.obstacle_yi6 - app.obstacle_yi5;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return;  % Exit the loop to stop further movement
                        end

                        pause(0.035)
                    end
            
                    delete(app.disp_capacitor1);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
                          
                elseif app.obs_choose == 6
                    % Capacitor 2
                    app.disp_capacitor2 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi5], ...
                        'YData', [app.obstacle_yi1 , app.obstacle_yi2], ...
                        'CData' , app.img6 , 'AlphaData', app.alpha6);
            
                    for dX = 0 : 1 : 130
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_capacitor2.XData = [app.obstacle_xi1 - dX, app.obstacle_xi5 - dX];

                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX + 7.25;
                        obsY = app.obstacle_yi1;
                        obsW = app.obstacle_xi5 - app.obstacle_xi1 - 14.5;
                        obsH = app.obstacle_yi2 - app.obstacle_yi1;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Secondary hitbox
                        obsRect2 = [obsX - 7.25, ...
                        obsY + 13.5, ...
                        obsW + 14.5, ...
                        obsH - 28.5];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1) || app.checkCollision(playerRect, obsRect2)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return; % Exits loop if there is a collisons
                        end

                        pause(0.035)
                    end
            
                    delete(app.disp_capacitor2);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
          
                elseif app.obs_choose == 7
                    % Truss
                    app.disp_truss1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi4], ...
                        'YData', [app.obstacle_yi7 , app.obstacle_yi8], ...
                        'CData' , app.img7 , 'AlphaData', app.alpha7);
            
                    for dX = 0 : 1 : 160
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_truss1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi4 - dX];

                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX;
                        obsY = app.obstacle_yi7 - 0.1;
                        obsW = app.obstacle_xi4 - app.obstacle_xi1;
                        obsH = app.obstacle_yi8 - app.obstacle_yi7 - 24.9;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Secondary hitbox
                        obsRect2 = [obsX + 5, ...
                        obsY + 0.1, ...
                        obsW - 10, ...
                        obsH + 5];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1) || app.checkCollision(playerRect, obsRect2)
                            pause(1)
                            app.Panel.Visible = true; % Show game over panel

                            % Stop and clean up music completely
                            app.musicDisabled = true; % Prevent any automatic restart
                            if ~isempty(app.music) && isvalid(app.music)
                                stop(app.music);
                            end

                            % Stop and delete the loop timer
                            if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                                stop(app.musicLoopTimer);
                                delete(app.musicLoopTimer);
                                app.musicLoopTimer = [];
                            end

                            % Play game over sound
                            play(app.music_end);

                        return; % Exits loop if there is a collisons
                        end


                        pause(0.035)
                    end
            
                    delete(app.disp_truss1);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
            
                elseif app.obs_choose == 8
                    % Hole
                    app.disp_hole1 = image(app.UIAxes, 'XData', [app.obstacle_xi1 , app.obstacle_xi4], ...
                        'YData', [app.obstacle_yi9 , app.obstacle_yi10], ...
                        'CData' , app.hole1_img);
            
                    for dX = 0 : 1 : 160
                        while app.isPaused
                            pause(0.1);
                        end
                        app.disp_hole1.XData = [app.obstacle_xi1 - dX, app.obstacle_xi4 - dX];

                        % Construct obstacle hitbox
                        obsX = app.obstacle_xi1 - dX + 2.5;
                        obsY = app.obstacle_yi9;
                        obsW = app.obstacle_xi4 - app.obstacle_xi1 - 5;
                        obsH = app.obstacle_yi10 - app.obstacle_yi9;
                        obsRect1 = [obsX, obsY, obsW, obsH];

                        % Gets player hitbox
                        playerRect = app.Player_orientation.Position;

                        % Check collision
                        if app.checkCollision(playerRect, obsRect1)
                            pause(1)
                        app.Panel.Visible = true; % Show game over panel
                        stop(app.music); % Stop music
                        app.musicDisabled = true; % Prevent auto-restart

                        % Stop and delete the loop timer
                        if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
                            stop(app.musicLoopTimer);
                            delete(app.musicLoopTimer);
                            app.musicLoopTimer = [];
                        end

                        play(app.music_end); % Play game over sound

                        return;  % Exit the loop to stop further movement

                        end    

                        pause(0.035)
                    end
            
                    delete(app.disp_hole1);
                    app.scoreField.Value = app.scoreField.Value + 1;

                    app.music_coin = audioplayer(app.y_coin, app.Fs_coin);
                    play(app.music_coin);
       
                end

            end

            % This code allows to probe each if/elseif statement
            % individually to see the hit boxes for the obstacles but if
            % there is only one hitbox then comment out the second
            % hitbox handling parts. Put this code between the collision
            % check and get players hit box.
%{
% Clear previous drawings (safe check)
if ~isempty(app.obsBoxHandle1) && isgraphics(app.obsBoxHandle1)
    delete(app.obsBoxHandle1);
end
if ~isempty(app.obsBoxHandle2) && isgraphics(app.obsBoxHandle2)
    delete(app.obsBoxHandle2);
end

% Draw primary hitbox (red)
app.obsBoxHandle1 = rectangle(app.UIAxes, ...
    'Position', obsRect1, ...
    'EdgeColor', 'r', 'LineWidth', 2, 'FaceColor', 'none');

% Draw secondary hitbox (blue)
app.obsBoxHandle2 = rectangle(app.UIAxes, ...
    'Position', obsRect2, ...
    'EdgeColor', 'b', 'LineWidth', 2, 'FaceColor', 'none');
%}

        end

        % Button pushed function: JumpButton
        function JumpButtonPushed(app, event)

            % Sets jump time
            app.jumpTime = 0;
        
            app.JumpButton.Enable = 'off';
        
            app.jumpTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.016, ...
                'TimerFcn', @(~,~) updateJumpFrame(app));
        
            start(app.jumpTimer);
            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
    % Toggle pause state
    app.isPaused = ~app.isPaused;

    if app.isPaused
        app.PauseButton.Text = 'Resume';
        app.JumpButton.Enable = 'off';
        
        % Stop music completely
        if ~isempty(app.music) && isplaying(app.music)
            stop(app.music);
        end
        
        % Delete the timer
        if ~isempty(app.musicLoopTimer) && isvalid(app.musicLoopTimer)
            stop(app.musicLoopTimer);
            delete(app.musicLoopTimer);
            app.musicLoopTimer = [];
        end
        
    else
        app.PauseButton.Text = 'Pause';
        app.JumpButton.Enable = 'on';
        
        % Only restart if not in game over state
        if ~app.musicDisabled
            restartMusicLoop(app);
        end
    end
    
    focus(app.UIFigure);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
    
            key = event.Key;
            % can only jump with spacebase if space is pressed, game is not
            % paused, jump button is enabled
            if (strcmp(key, 'space') && ~app.isPaused && strcmp(app.JumpButton.Enable, 'on'))

               JumpButtonPushed(app, event);  
            end
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
            
    % Reset game state
    app.Panel.Visible = false;
    app.TabGroup.SelectedTab = app.MainMenuTab;
    app.musicDisabled = false; % Re-enable music
    
    % Reset score and other game variables as needed
    app.scoreField.Value = 0;
    
    % Clear any remaining music objects
    if ~isempty(app.music)
        stop(app.music);
    end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HowtoPlayTab = uitab(app.TabGroup);
            app.HowtoPlayTab.Title = 'How to Play';
            app.Image3 = uiimage(app.HowtoPlayTab);
            app.Image3.ImageSource = 'background.png';
            app.GettingStartedLabel_2 = uilabel(app.HowtoPlayTab);
            app.GettingStartedLabel_2.Text = 'Getting Started:';
            app.Label_8 = uilabel(app.HowtoPlayTab);
            app.Label_8.Text = 'Navigate to the Main Menu tab above. Using the color pickers you can select your players inner color and edge/border color. When your ready, click start to start the game. To jump either use the jump button on the screen or use space bar. Space bar may require an initial click of your cursor on the screen to make it start working. If you need a break you can click the on-screen pause button and resume whenever you want as long as you keep the app window open and running. The game ends when you hit an obstacle and to replay the game go back to the Main Menu tab and click start.';
            app.ObjectiveLabel = uilabel(app.HowtoPlayTab);
            app.ObjectiveLabel.Text = 'Objective:';
            app.Label_9 = uilabel(app.HowtoPlayTab);
            app.Label_9.Text = 'Avoid hitting obstacles by jumping over them. Obstacles spawn once the previous one is cleared and they vary in difficulty. The higher the score the better.';
            app.MainMenuTab = uitab(app.TabGroup);
            app.MainMenuTab.Title = 'Main Menu';
            app.Image = uiimage(app.MainMenuTab);
            app.Image.ImageSource = 'background.png';
            app.TitleImage = uiimage(app.MainMenuTab);
            app.TitleImage.ImageSource = 'engineering dash title.png';
            app.CharacterEdgeColorColorPickerLabel = uilabel(app.MainMenuTab);
            app.CharacterEdgeColorColorPickerLabel.Text = 'Character Edge Color';
            app.CharacterEdgeColorColorPicker = uicolorpicker(app.MainMenuTab);
            app.CharcterColorColorPickerLabel = uilabel(app.MainMenuTab);
            app.CharcterColorColorPickerLabel.Text = 'Charcter Color';
            app.CharcterColorColorPicker = uicolorpicker(app.MainMenuTab);
            app.StartButton = uibutton(app.MainMenuTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DevelopersErnestoTellezPerezShivPatelChatalsohelpedabittooLabel = uilabel(app.MainMenuTab);
            app.DevelopersErnestoTellezPerezShivPatelChatalsohelpedabittooLabel.Text = 'Developers: Ernesto Tellez Perez & Shiv Patel (Chat also helped a bit too)';
            app.ENGRDashTab = uitab(app.TabGroup);
            app.ENGRDashTab.Title = 'ENGR Dash';
            app.UIAxes = uiaxes(app.ENGRDashTab);
            xlabel(app.UIAxes, 'x-axis(m)')
            ylabel(app.UIAxes, 'y-axis(m)')
            app.JumpButton = uibutton(app.ENGRDashTab, 'push');
            app.JumpButton.ButtonPushedFcn = createCallbackFcn(app, @JumpButtonPushed, true);
            app.JumpButton.Text = 'Jump';
            app.PauseButton = uibutton(app.ENGRDashTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.SCOREEditFieldLabel = uilabel(app.ENGRDashTab);
            app.SCOREEditFieldLabel.Text = 'SCORE';
            app.scoreField = uieditfield(app.ENGRDashTab, 'numeric');
            app.scoreField.Editable = 'off';
            app.Panel = uipanel(app.ENGRDashTab);
            app.Panel.Visible = 'off';
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'Gameover.png';
            app.PlayAgainButton = uibutton(app.Panel, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again';
            app.CommonIssuesTab = uitab(app.TabGroup);
            app.CommonIssuesTab.Title = 'Common Issues';
            app.Image2 = uiimage(app.CommonIssuesTab);
            app.Image2.ImageSource = 'background.png';
            app.CommonIssuesPossibleSolutionsLabel_2 = uilabel(app.CommonIssuesTab);
            app.CommonIssuesPossibleSolutionsLabel_2.Text = 'Common Issues & Possible Solutions:';
            app.Label_5 = uilabel(app.CommonIssuesTab);
            app.Label_5.Text = 'If it says internal device error completely close out of MATLAB and then open it back up again.';
            app.Label_6 = uilabel(app.CommonIssuesTab);
            app.Label_6.Text = 'If there are issues with the music running other than the one mentiod previously make sure the music file has exact same name in your working folder as it is in the code. If not change one to match the other.';
            app.Label_7 = uilabel(app.CommonIssuesTab);
            app.Label_7.Text = 'There may be a bit of lag or stutter that occurs due to some of the processing power limitations of MATLAB. Playing the game on a more powerful system may help in decreasing the lag.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Geodash

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end